classdef CSGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadParamatersMenu           matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        ControllerParamatersTab      matlab.ui.container.Tab
        PendulumLengthSliderLabel    matlab.ui.control.Label
        PendulumLengthSlider         matlab.ui.control.Slider
        ControllerTypeDropDownLabel  matlab.ui.control.Label
        ControllerTypeDropDown       matlab.ui.control.DropDown
        InchesEditFieldLabel         matlab.ui.control.Label
        InchesEditField              matlab.ui.control.NumericEditField
        GainKEditFieldLabel          matlab.ui.control.Label
        GainKEditField               matlab.ui.control.NumericEditField
        SaveParamatersButton         matlab.ui.control.Button
        CoefficientsSeperatecoefficentsusingPanel  matlab.ui.container.Panel
        NumeratorIEditFieldLabel     matlab.ui.control.Label
        NumeratorIEditField          matlab.ui.control.EditField
        DenominatorDEditFieldLabel   matlab.ui.control.Label
        DenominatorDEditField        matlab.ui.control.EditField
        SimulationOutputTab          matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private, SetObservable)
        
        Value % Description
    end
    
    methods (Access = private)
        
        function results = enableSaveParamButtonCheck(app)
            index = app.ControllerTypeDropDown.Value;    
            switch index
                case 0
                    
            end
        end
        
        function results = dataEntryErrorCheck(app, value)
            valVector = strsplit(value,",");
            valArray = str2double(valVector);
            if(isnan(valArray))
                  msg = sprintf('Invalid input has been detected. Please ensure coefficients are numerical and seperated by commas.');
                  uiconfirm(app.UIFigure,  msg, ...
               'Invalid Data', 'Options', {'Ok'}, 'DefaultOption', 1, 'Icon', 'info');
           results = NaN;
            else
                results = valArray;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             set(app.InchesEditField, 'Value', app.PendulumLengthSlider.Value);
             plot(app.UIAxes, [1 2 3 4 5], [1 2 5 6 7]);
        end

        % Value changed function: PendulumLengthSlider
        function PendulumLengthSliderValueChanged(app, event)
            value = app.PendulumLengthSlider.Value;
            app.InchesEditField.Value = value;
        end

        % Value changing function: PendulumLengthSlider
        function PendulumLengthSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.InchesEditField.Value = changingValue;
        end

        % Value changed function: ControllerTypeDropDown
        function ControllerTypeDropDownValueChanged(app, event)
            value = app.ControllerTypeDropDown.Value;
             app.GainKEditField.Enable = true;

            
            switch value
                case 'PID'
                    app.CoefficientsSeperatecoefficentsusingPanel.Visible = true;
                    app.DenominatorDEditField.Enable = true;
                    app.NumeratorIEditField.Enable = true;
                case 'PI'
                    app.CoefficientsSeperatecoefficentsusingPanel.Visible = true;
                    app.DenominatorDEditField.Enable = false;
                    app.NumeratorIEditField.Enable = true;
                case 'PD'
                    app.CoefficientsSeperatecoefficentsusingPanel.Visible = true;
                    app.DenominatorDEditField.Enable = true;
                    app.NumeratorIEditField.Enable = false;
                case 'P'
                    app.CoefficientsSeperatecoefficentsusingPanel.Visible = false;
                    app.DenominatorDEditField.Enable = false;
                    app.NumeratorIEditField.Enable = false;
%                 case 'Lag'
%                     
%                 case 'Lead'
%                     
%                 case 'Lead Lag'
%                     
                 case 'Generalized'
                    app.CoefficientsSeperatecoefficentsusingPanel.Visible = true;
                    app.DenominatorDEditField.Enable = true;
                    app.NumeratorIEditField.Enable = true;
                    
                case ''
                    app.CoefficientsSeperatecoefficentsusingPanel.Visible = false;
                    app.DenominatorDEditField.Enable = false;
                    app.NumeratorIEditField.Enable = false;
                    app.GainKEditField.Enable = false;
             end
        end

        % Button pushed function: SaveParamatersButton
        function SaveParamatersButtonPushed(app, event)
        %TODO: This should be split off into utility functions to
        %streamline the process and DRY the code a bit. There is a lot
        %being done in a single button press overall. I may use the
        %callback functions for the editfields to do this error checking to
        %offload the work a bit more evenly. The below also assumes ALOT in
        %regards to input as is. It only really works for a PID controller
        %from how I understand the other controller types will be handled.
%         numVector = strsplit(app.NumeratorIEditField.Value,",");
%         denVector = strsplit(app.DenominatorDEditField.Value,",");
%         field = "NOERROR";
%         numArray = []; denArray = [];
%         
%         if(app.NumeratorIEditField.Enable == "on")
%             numArray = str2double(numVector);
%             if(isnan(numArray))
%                  field = "Numerator";
%             end
%         end
%         if(app.DenominatorDEditField.Enable == "on")
%             denArray = str2double(denVector);
%             if(isnan(denArray))
%                  if(field == "Numerator")
%                      field = "Numerator and Denominator";
%                  else
%                      field = "Denominator";
%                  end
%             end
%         end
        %TODO: This does wind up doing more work than needed overall. If
        %the user never changed one, or both, of the textfields there
        %shouldn't be any need to assign a variable again in the base
        %workpace.
        numerator = getappdata(app.UIFigure,'NumeratorEntry');
        if(~isnan(numerator))
            assignin("base",'numArray', numerator);
        end
        denominator = getappdata(app.UIFigure,'DenominatorEntry');
        if(~isnan(denominator))
            assignin("base",'denArray', denominator);
        end
            assignin("base", 'PendLengthInches', app.InchesEditField.Value);
            assignin("base", 'ControllerGain', app.GainKEditField.Value);
            assignin("base", 'ControllerType', app.ControllerTypeDropDown.Value);
            transF = tf(numerator, denominator);
            assignin("base", 'TransferFunction', transF);
        
        selection = uiconfirm(app.UIFigure,  "Successfully saved paramaters to workspace. Would you like to also save the entered parameters to file?", ...
               'Save to File?', 'Options', {'Yes', 'No'}, 'DefaultOption', 1, 'Icon', 'question');
         if(selection == 'Yes')
             [file, path, indx] = uiputfile('*.mat', 'Save to File', 'simParamaters.mat');
         end
        end

        % Menu selected function: LoadParamatersMenu
        function LoadParamatersMenuSelected(app, event)
            uiopen('simParamaters.mat');
        end

        % Value changed function: InchesEditField
        function InchesEditFieldValueChanged(app, event)
            value = app.InchesEditField.Value;
            if (value < 1 || value > 24)
                app.InchesEditField.Value = 12;
            end
            
        end

        % Value changed function: NumeratorIEditField
        function NumeratorIEditFieldValueChanged(app, event)
        setappdata(app.UIFigure, 'NumeratorEntry', dataEntryErrorCheck(app,app.NumeratorIEditField.Value));
        end

        % Value changed function: DenominatorDEditField
        function DenominatorDEditFieldValueChanged(app, event)
           setappdata(app.UIFigure, 'DenominatorEntry', dataEntryErrorCheck(app,app.DenominatorDEditField.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadParamatersMenu = uimenu(app.FileMenu);
            app.LoadParamatersMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadParamatersMenuSelected, true);
            app.LoadParamatersMenu.Text = 'Load Paramaters';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControllerParamatersTab = uitab(app.TabGroup);
            app.ControllerParamatersTab.Title = 'Controller Paramaters';
            app.PendulumLengthSliderLabel = uilabel(app.ControllerParamatersTab);
            app.PendulumLengthSliderLabel.Text = {'Pendulum'; 'Length'};
            app.PendulumLengthSlider = uislider(app.ControllerParamatersTab);
            app.PendulumLengthSlider.Limits = [1 24];
            app.PendulumLengthSlider.MajorTicks = [1 4 8 12 16 20 24];
            app.PendulumLengthSlider.ValueChangedFcn = createCallbackFcn(app, @PendulumLengthSliderValueChanged, true);
            app.PendulumLengthSlider.ValueChangingFcn = createCallbackFcn(app, @PendulumLengthSliderValueChanging, true);
            app.PendulumLengthSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 14 15 16 17 18 19 20 21 22 23 24];
            app.PendulumLengthSlider.Value = 1;
            app.ControllerTypeDropDownLabel = uilabel(app.ControllerParamatersTab);
            app.ControllerTypeDropDownLabel.Text = {'Controller'; 'Type'};
            app.ControllerTypeDropDown = uidropdown(app.ControllerParamatersTab);
            app.ControllerTypeDropDown.Items = {'PID', 'PI', 'PD', 'P', 'Lag', 'Lead', 'Lag Lead', 'Generalized'};
            app.ControllerTypeDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.ControllerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeDropDownValueChanged, true);
            app.ControllerTypeDropDown.Value = '0';
            app.InchesEditFieldLabel = uilabel(app.ControllerParamatersTab);
            app.InchesEditFieldLabel.Text = 'Inches';
            app.InchesEditField = uieditfield(app.ControllerParamatersTab, 'numeric');
            app.InchesEditField.Limits = [1 24];
            app.InchesEditField.RoundFractionalValues = 'on';
            app.InchesEditField.ValueChangedFcn = createCallbackFcn(app, @InchesEditFieldValueChanged, true);
            app.InchesEditField.Editable = 'off';
            app.InchesEditField.Value = 1;
            app.GainKEditFieldLabel = uilabel(app.ControllerParamatersTab);
            app.GainKEditFieldLabel.Text = 'Gain (K)';
            app.GainKEditField = uieditfield(app.ControllerParamatersTab, 'numeric');
            app.GainKEditField.Value = 1;
            app.SaveParamatersButton = uibutton(app.ControllerParamatersTab, 'push');
            app.SaveParamatersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamatersButtonPushed, true);
            app.SaveParamatersButton.Icon = 'iconfinder_floppy_285657.png';
            app.SaveParamatersButton.Tooltip = {'Save input paramters to file.'};
            app.SaveParamatersButton.Text = 'Save Paramaters';
            app.CoefficientsSeperatecoefficentsusingPanel = uipanel(app.ControllerParamatersTab);
            app.CoefficientsSeperatecoefficentsusingPanel.Title = 'Coefficients (Seperate coefficents using ,)';
            app.NumeratorIEditFieldLabel = uilabel(app.CoefficientsSeperatecoefficentsusingPanel);
            app.NumeratorIEditFieldLabel.Text = 'Numerator (I)';
            app.NumeratorIEditField = uieditfield(app.CoefficientsSeperatecoefficentsusingPanel, 'text');
            app.NumeratorIEditField.ValueChangedFcn = createCallbackFcn(app, @NumeratorIEditFieldValueChanged, true);
            app.DenominatorDEditFieldLabel = uilabel(app.CoefficientsSeperatecoefficentsusingPanel);
            app.DenominatorDEditFieldLabel.Text = 'Denominator (D)';
            app.DenominatorDEditField = uieditfield(app.CoefficientsSeperatecoefficentsusingPanel, 'text');
            app.DenominatorDEditField.ValueChangedFcn = createCallbackFcn(app, @DenominatorDEditFieldValueChanged, true);
            app.SimulationOutputTab = uitab(app.TabGroup);
            app.SimulationOutputTab.Title = 'Simulation Output';
            app.UIAxes = uiaxes(app.SimulationOutputTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end